classdef RockPaperScissorsLizardSpock < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        ResetButton            matlab.ui.control.Button
        HumanLabel             matlab.ui.control.Label
        GameRulesLabel         matlab.ui.control.Label
        ComputerHandLabel      matlab.ui.control.Label
        ComputerTotalwonLabel  matlab.ui.control.Label
        HumantotalWonLabel     matlab.ui.control.Label
        computerwon            matlab.ui.control.Label
        HumanWinsLabel         matlab.ui.control.Label
        ReasonLabel            matlab.ui.control.Label
        winnerLabel            matlab.ui.control.Label
        ComputerHandShape      matlab.ui.control.Image
        SpockButton            matlab.ui.control.Button
        ScissorsButton         matlab.ui.control.Button
        LizardButton           matlab.ui.control.Button
        PaperButton            matlab.ui.control.Button
        RockButton             matlab.ui.control.Button
        humanhand              matlab.ui.control.Image
        Gamerule               matlab.ui.control.Image
    end

   
   properties (Access = public)
    ...
    humanWins int32 = 0 % initialize to 0
    computerWins int32 = 0 % initialize to 0
     fileID % file identifier for activity log
    ...
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function OpeningFcn(app)
            set(app.Gamerule, 'Visible', 'on');
            % Show the game rules image
            app.humanhand.ImageSource = 'gamerule.png';
            app.humanhand.Visible = 'on';
            app.ComputerHandShape.ImageSource = 'gamerule.png';
            app.ComputerHandShape.Visible = 'on';
            app.HumanWinsLabel.Text = '0';
            app.computerwon.Text = '0';
            app.ResetButton.Text = 'Reset';
            % Create a new file for the activity log and open it in append mode
            app.fileID = fopen('activity_log.txt', 'a');
            
        end

        % Button pushed function: RockButton
        function RockButtonPushed(app, event)
           
           % Update the image object to show the rock hand shape
            app.humanhand.ImageSource = 'rock.png';
                    
            % Generate a random hand shape for the computer app
            computerHandShape = randi(5);
                    
            % Set the image data for the computerHandShapeImage based on the hand shape selected by the computer app
            switch computerHandShape
                case 1
                    % Set image data for Rock hand shape
                    app.ComputerHandShape.ImageSource = 'rock.png';
                case 2
                    % Set image data for Paper hand shape
                    app.ComputerHandShape.ImageSource = 'paper.png';
                case 3
                    % Set image data for Scissors hand shape
                    app.ComputerHandShape.ImageSource = 'Scissors.png';
                case 4
                    % Set image data for Lizard hand shape
                    app.ComputerHandShape.ImageSource = 'lizard.png';
                case 5
                    % Set image data for Spock hand shape
                    app.ComputerHandShape.ImageSource = 'spock.png';
            end
                    
            % Determine the winner of the hand using the Rock Paper Scissors Lizard Spock rules
            if computerHandShape == 1
                % If the computer chose rock, it's a tie
                app.winnerLabel.Text = 'Tie';
                app.ReasonLabel.Text = 'Both players chose Rock';
            elseif computerHandShape == 2
                % If the computer chose paper, the computer wins
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Paper covers Rock';
                app.computerWins = app.computerWins + 1; % increment computer wins
                % Scissors
            elseif computerHandShape == 3
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Rock crushes Scissors';
                app.humanWins = app.humanWins + 1; % increment human wins
                % Lizard
            elseif computerHandShape == 4
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Rock crushes Lizard';
                app.humanWins = app.humanWins + 1; % increment human wins
            else
                % Otherwise, the human player wins
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Spock vaporizes rock';
                app.computerWins = app.computerWins + 1; % increment computer wins
            end
             % Write the hand shape selected by the human player to the activity log file
                fprintf(app.fileID, 'Human player chose: %s\n', app.humanhand.ImageSource);
                % Write the hand shape selected by the computer to the activity log file
                fprintf(app.fileID, 'Computer chose: %s\n', app.ComputerHandShape.ImageSource);
                % Write the winner of the hand and the reason for the win to the activity log file
                fprintf(app.fileID, 'Winner: %s\nReason: %s\n', app.winnerLabel.Text, app.ReasonLabel.Text);
                if strcmp(app.winnerLabel.Text, 'Human')
                    app.humanWins = app.humanWins + 1;
                    app.HumanWinsLabel.Text = num2str(app.humanWins);
                    elseif strcmp(app.winnerLabel.Text, 'Computer')
                    app.computerWins = app.computerWins + 1;
                    app.computerwon.Text = num2str(app.computerWins);
                 end


        end

        % Button pushed function: LizardButton
        function LizardButtonPushed(app, event)
            % Update the image object to show the lizard hand shape
            app.humanhand.ImageSource = 'Lizard.png';
             % Generate a random hand shape for the computer app
            computerHandShape = randi(5);
            
            % Set the image data for the computerHandShapeImage based on the hand shape selected by the computer app
            switch computerHandShape
                case 1
                    % Set image data for Rock hand shape
                    app.ComputerHandShape.ImageSource = 'rock.png';
                case 2
                    % Set image data for Paper hand shape
                    app.ComputerHandShape.ImageSource = 'paper.png';
                case 3
                    % Set image data for Scissors hand shape
                    app.ComputerHandShape.ImageSource = 'Scissors.png';
                case 4
                    % Set image data for Lizard hand shape
                    app.ComputerHandShape.ImageSource = 'lizard.png';
                case 5
                    % Set image data for Spock hand shape
                    app.ComputerHandShape.ImageSource = 'spock.png';
            end
            % Determine the winner of the hand using the Rock Paper Scissors Lizard Spock rules
            if computerHandShape == 1
                % If the computer chose rock, it's a tie
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Rock crashes Lizard';
                app.computerWins = app.computerWins + 1; % increment computer wins


            elseif computerHandShape == 2
                % If the computer chose paper, the computer wins
                app.winnerLabel.Text = 'Human Player Win ';
                app.ReasonLabel.Text = 'Lizard eats Paper';
                app.humanWins = app.humanWins + 1; % increment human wins

            elseif computerHandShape == 3 
                 app.winnerLabel.Text = 'Computer Wins';
                 app.ReasonLabel.Text = 'Scissors decapitate Lizard';
                 app.computerWins = app.computerWins + 1; % increment computer wins
            elseif computerHandShape == 4
                 app.winnerLabel.Text = 'Tie';
                 app.ReasonLabel.Text = 'Both Chose Lizard';
                 app.humanWins = app.humanWins + 1; % increment human wins
            else
                % Otherwise, the human player wins
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Lizard Poisons Spock';
                app.humanWins = app.humanWins + 1; % increment human wins
            end
             % Update the labels to display the current number of human wins and computer wins
            app.HumanWinsLabel.Text = int2str(app.humanWins); 
            app.computerwon.Text = int2str(app.computerWins); 

        end

        % Button pushed function: ScissorsButton
        function ScissorsButtonPushed(app, event)
            % Update the image object to show the Scissors hand shape
            app.humanhand.ImageSource = 'Scissors.png';
             % Generate a random hand shape for the computer app
            computerHandShape = randi(5);
            
            % Set the image data for the computerHandShapeImage based on the hand shape selected by the computer app
            switch computerHandShape
                case 1
                    % Set image data for Rock hand shape
                    app.ComputerHandShape.ImageSource = 'rock.png';
                case 2
                    % Set image data for Paper hand shape
                    app.ComputerHandShape.ImageSource = 'paper.png';
                case 3
                    % Set image data for Scissors hand shape
                    app.ComputerHandShape.ImageSource = 'Scissors.png';
                case 4
                    % Set image data for Lizard hand shape
                    app.ComputerHandShape.ImageSource = 'lizard.png';
                case 5
                    % Set image data for Spock hand shape
                    app.ComputerHandShape.ImageSource = 'spock.png';
            end
            % Determine the winner of the hand using the Rock Paper Scissors Lizard Spock rules
            if computerHandShape == 1
                % If the computer chose rock, it's a tie
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'As it always has, rock crushes scissors';
                app.computerWins = app.computerWins + 1; % increment computer wins

            elseif computerHandShape == 2
                % If the computer chose paper, the computer wins
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Scissors cuts paper';
                app.humanWins = app.humanWins + 1; % increment human wins

            elseif computerHandShape == 3 
                 app.winnerLabel.Text = 'it is a tie';
                 app.ReasonLabel.Text = 'Both players chose Scissors';
                 app.humanWins = app.humanWins + 1; % increment human wins

            elseif computerHandShape == 4 
                 app.winnerLabel.Text = 'Computer Wins';
                 app.ReasonLabel.Text = 'Spock Smashes Scissors';
                 app.computerWins = app.computerWins + 1; % increment computer wins


            else
                % Otherwise, the human player wins
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Rock crushes Scissors/Lizard/Spock';
                app.humanWins = app.humanWins + 1; % increment human wins

            end
             % Update the labels to display the current number of human wins and computer wins
            app.HumanWinsLabel.Text = int2str(app.humanWins); 
            app.computerwon.Text = int2str(app.computerWins); 

            % Write the hand shape selected by the human player to the activity log file
                fprintf(app.fileID, 'Human player chose: %s\n', app.humanhand.ImageSource);
                % Write the hand shape selected by the computer to the activity log file
                fprintf(app.fileID, 'Computer chose: %s\n', app.ComputerHandShape.ImageSource);
                % Write the winner of the hand and the reason for the win to the activity log file
                fprintf(app.fileID, 'Winner: %s\nReason: %s\n', app.winnerLabel.Text, app.ReasonLabel.Text);
            
        end

        % Button pushed function: PaperButton
        function PaperButtonPushed(app, event)
             % Update the image object to show the paper hand shape
            app.humanhand.ImageSource = 'paper.png';
            % Generate a random hand shape for the computer app
            computerHandShape = randi(5);
            
            % Set the image data for the computerHandShapeImage based on the hand shape selected by the computer app
            switch computerHandShape
                case 1
                    % Set image data for Rock hand shape
                    app.ComputerHandShape.ImageSource = 'rock.png';
                case 2
                    % Set image data for Paper hand shape
                    app.ComputerHandShape.ImageSource = 'paper.png';
                case 3
                    % Set image data for Scissors hand shape
                    app.ComputerHandShape.ImageSource = 'Scissors.png';
                case 4
                    % Set image data for Lizard hand shape
                    app.ComputerHandShape.ImageSource = 'lizard.png';
                case 5
                    % Set image data for Spock hand shape
                    app.ComputerHandShape.ImageSource = 'spock.png';
            end
            % Determine the winner of the hand using the Rock Paper Scissors Lizard Spock rules
            if computerHandShape == 1
                % If the computer chose rock, it's a tie
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Paper covers rock';
                app.computerWins = app.computerWins + 1; % increment computer wins
          
            elseif computerHandShape == 2
                % If the computer chose paper, the computer wins
                app.winnerLabel.Text = 'it is a tie';
                app.ReasonLabel.Text = 'both selected Paper';
                app.computerWins = app.computerWins + 1; % increment computer wins

            elseif computerHandShape == 3
                % If the computer chose Scissors, the computer wins
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Scissors cuts paper';
                app.computerWins = app.computerWins + 1; % increment computer wins

            elseif computerHandShape == 4
                % If the computer chose lizard, the computer wins
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Lizard Eats paper';
                app.computerWins = app.computerWins + 1; % increment computer wins
            else
                
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Paper disproves Spock';
                app.humanWins = app.humanWins + 1; % increment human wins


            end
             % Update the labels to display the current number of human wins and computer wins
            app.HumanWinsLabel.Text = int2str(app.humanWins); 
            app.computerwon.Text = int2str(app.computerWins); 

            % Write the hand shape selected by the human player to the activity log file
                fprintf(app.fileID, 'Human player chose: %s\n', app.humanhand.ImageSource);
                % Write the hand shape selected by the computer to the activity log file
                fprintf(app.fileID, 'Computer chose: %s\n', app.ComputerHandShape.ImageSource);
                % Write the winner of the hand and the reason for the win to the activity log file
                fprintf(app.fileID, 'Winner: %s\nReason: %s\n', app.winnerLabel.Text, app.ReasonLabel.Text);


        end

        % Button pushed function: SpockButton
        function SpockButtonPushed(app, event)
             % Update the image object to show the Spock hand shape
            app.humanhand.ImageSource = 'spock.png';
             % Generate a random hand shape for the computer app
            computerHandShape = randi(5);
            
            % Set the image data for the computerHandShapeImage based on the hand shape selected by the computer app
            switch computerHandShape
                case 1
                    % Set image data for Rock hand shape
                    app.ComputerHandShape.ImageSource = 'rock.png';
                case 2
                    % Set image data for Paper hand shape
                    app.ComputerHandShape.ImageSource = 'paper.png';
                case 3
                    % Set image data for Scissors hand shape
                    app.ComputerHandShape.ImageSource = 'Scissors.png';
                case 4
                    % Set image data for Lizard hand shape
                    app.ComputerHandShape.ImageSource = 'lizard.png';
                case 5
                    % Set image data for Spock hand shape
                    app.ComputerHandShape.ImageSource = 'spock.png';
            end
            % Determine the winner of the hand using the Rock Paper Scissors Lizard Spock rules
            if computerHandShape == 1
                % If the computer chose rock, it's a tie
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Spock vaporizes rock';
                app.humanWins = app.humanWins + 1; % increment human wins


            elseif computerHandShape == 2
                % If the computer chose paper, the computer wins
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Paper disproves Spock';
                app.computerWins = app.computerWins + 1; % increment computer wins
            elseif computerHandShape == 3
                app.winnerLabel.Text = 'Human Player Wins';
                app.ReasonLabel.Text = 'Spock Smashes Scissors';
                app.humanWins = app.humanWins + 1; % increment human wins
             elseif computerHandShape == 4
                app.winnerLabel.Text = 'Computer Wins';
                app.ReasonLabel.Text = 'Lizard Poisons Spock';
                app.computerWins = app.computerWins + 1; % increment computer wins

            else
                % Otherwise, the human player wins
               app.winnerLabel.Text = 'it is A tie';
               app.ReasonLabel.Text = 'Both chose Spock';
               app.humanWins = app.humanWins + 1; % increment human wins

            end
             % Update the labels to display the current number of human wins and computer wins
            app.HumanWinsLabel.Text = int2str(app.humanWins); 
            app.computerwon.Text = int2str(app.computerWins); 
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
             % Reset labels and images to their initial state
            app.HumanWinsLabel.Text = '0';
            app.computerwon.Text = '0';
            app.ReasonLabel.Text = '';
            app.winnerLabel.Text = '';
            app.humanhand.ImageSource = 'gamerule.png';
            app.ComputerHandShape.ImageSource = 'gamerule.png';
            
            % Reset humanWins and computerWins properties to 0
            app.humanWins = 0;
            app.computerWins = 0;
            delete('activity_log.txt');


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Gamerule = uiimage(app.UIFigure);
            app.Gamerule.ImageSource = fullfile(pathToMLAPP, 'gamerule.png');
            app.humanhand = uiimage(app.UIFigure);
            app.humanhand.ImageSource = fullfile(pathToMLAPP, 'gamerule.png');
            app.RockButton = uibutton(app.UIFigure, 'push');
            app.RockButton.ButtonPushedFcn = createCallbackFcn(app, @RockButtonPushed, true);
            app.RockButton.Text = 'Rock';
            app.PaperButton = uibutton(app.UIFigure, 'push');
            app.PaperButton.ButtonPushedFcn = createCallbackFcn(app, @PaperButtonPushed, true);
            app.PaperButton.Text = 'Paper';
            app.LizardButton = uibutton(app.UIFigure, 'push');
            app.LizardButton.ButtonPushedFcn = createCallbackFcn(app, @LizardButtonPushed, true);
            app.LizardButton.Text = 'Lizard';
            app.ScissorsButton = uibutton(app.UIFigure, 'push');
            app.ScissorsButton.ButtonPushedFcn = createCallbackFcn(app, @ScissorsButtonPushed, true);
            app.ScissorsButton.Text = 'Scissors';
            app.SpockButton = uibutton(app.UIFigure, 'push');
            app.SpockButton.ButtonPushedFcn = createCallbackFcn(app, @SpockButtonPushed, true);
            app.SpockButton.Text = 'Spock';
            app.ComputerHandShape = uiimage(app.UIFigure);
            app.winnerLabel = uilabel(app.UIFigure);
            app.winnerLabel.Text = '';
            app.ReasonLabel = uilabel(app.UIFigure);
            app.ReasonLabel.Text = '';
            app.HumanWinsLabel = uilabel(app.UIFigure);
            app.HumanWinsLabel.Text = '';
            app.computerwon = uilabel(app.UIFigure);
            app.computerwon.Text = '';
            app.HumantotalWonLabel = uilabel(app.UIFigure);
            app.HumantotalWonLabel.Text = 'Human total Won';
            app.ComputerTotalwonLabel = uilabel(app.UIFigure);
            app.ComputerTotalwonLabel.Text = 'Computer Total won';
            app.ComputerHandLabel = uilabel(app.UIFigure);
            app.ComputerHandLabel.Text = 'Computer Hand';
            app.GameRulesLabel = uilabel(app.UIFigure);
            app.GameRulesLabel.Text = 'Game Rules';
            app.HumanLabel = uilabel(app.UIFigure);
            app.HumanLabel.Text = 'Human';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = RockPaperScissorsLizardSpock

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @OpeningFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end